classdef simulation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        RunningLamp              matlab.ui.control.Lamp
        RunningLampLabel         matlab.ui.control.Label
        EndButton                matlab.ui.control.Button
        StartButton              matlab.ui.control.Button
        Outlet4Lamp              matlab.ui.control.Lamp
        Outlet4LampLabel         matlab.ui.control.Label
        SystemStateLamp          matlab.ui.control.Lamp
        SystemStateLampLabel     matlab.ui.control.Label
        LoadWSlider              matlab.ui.control.Slider
        LoadWSliderLabel         matlab.ui.control.Label
        RateofChargeLamp         matlab.ui.control.Lamp
        RateofChargeLampLabel    matlab.ui.control.Label
        SolarSlider              matlab.ui.control.Slider
        SolarInputWSliderLabel   matlab.ui.control.Label
        Outlet3Lamp              matlab.ui.control.Lamp
        Outlet3LampLabel         matlab.ui.control.Label
        BatteryStateLamp         matlab.ui.control.Lamp
        BatteryStateLampLabel    matlab.ui.control.Label
        Outlet2Lamp              matlab.ui.control.Lamp
        Outlet2LampLabel         matlab.ui.control.Label
        TimeofDayStateLamp       matlab.ui.control.Lamp
        TimeofDayStateLampLabel  matlab.ui.control.Label
        BatterySlider            matlab.ui.control.Slider
        BatterySoCLabel          matlab.ui.control.Label
        Outlet1Lamp              matlab.ui.control.Lamp
        Outlet1Label             matlab.ui.control.Label
        TimeofDaySlider          matlab.ui.control.Slider
        TimeofDaySliderLabel     matlab.ui.control.Label
        UserInputLabel           matlab.ui.control.Label
        UIAxes_4                 matlab.ui.control.UIAxes
        UIAxes_3                 matlab.ui.control.UIAxes
        UIAxes_2                 matlab.ui.control.UIAxes
        UIAxes                   matlab.ui.control.UIAxes
    end


    properties (Access = private)
    Timer   % Timer for simulation loop
    end

    methods (Access = private)

        function fsmTimerCallback(app)
            solarInput_W = app.SolarSlider.Value;
            batterySoC_pct = app.BatterySlider.Value;
            discharge_W = app.LoadWSlider.Value;
            timeOfDay_State = app.TimeofDaySlider.Value;

            % 🔗 Call external FSM function
            [systemState, battery_State, rateOfCharge_State] = runFSM(solarInput_W, batterySoC_pct, discharge_W, timeOfDay_State);

            disp(['systemState: ', systemState]);
            disp(['battery_State: ', battery_State]);
            disp(['rateOfCharge_State: ', rateOfCharge_State]);

            % Update System State Indicatior
            switch systemState
                case 'NORMAL'
                    app.SystemStateLamp.Color = [0 1 0]; % Green
                case 'ALERT1'
                    app.SystemStateLamp.Color = [1 1 0]; % Yellow
                case 'ALERT2'
                    app.SystemStateLamp.Color = [1 0.5 0]; % Orange
                case 'CRITICAL'
                    app.SystemStateLamp.Color = [1 0 0]; % Yellow
            end

            % Update Battery State Indicatiors
            switch battery_State
                case 0
                    app.BatteryStateLamp.Color = [0 1 0]; % Green
                case 1
                    app.BatteryStateLamp.Color = [1 1 0]; % Yellow
                case 2
                    app.BatteryStateLamp.Color = [1 0.5 0]; % Orange
                case 3
                    app.BatteryStateLamp.Color = [1 0 0]; % Red
            end

            % Update Rate of Charge Indicatiors
            switch rateOfCharge_State
                case 0
                    app.RateofChargeLamp.Color = [0 1 0]; % Green
                case 1
                    app.RateofChargeLamp.Color = [1 0 0]; % Red
            end
            % Update Time of Day Indicatiors
            switch timeOfDay_State
                case 0
                    app.TimeofDayStateLamp.Color = [0 1 0]; % Green
                case 1
                    app.TimeofDayStateLamp.Color = [1 1 0]; % Red
                case 2
                    app.TimeofDayStateLamp.Color = [1 0 0]; % Red
            end

        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.RunningLamp.Color = [0 1 0];
            app.Timer = timer( ...
            'ExecutionMode', 'fixedSpacing', ...
            'Period', 0.5, ...
            'TimerFcn', @(~,~) app.fsmTimerCallback());
            start(app.Timer);
        end

        % Button pushed function: EndButton
        function EndButtonPushed(app, event)
            app.RunningLamp.Color = [1 0 0];
            stop(app.Timer);
            delete(app.Timer);
        end

        % Value changed function: TimeofDaySlider
        function TimeofDaySliderValueChanged(app, event)
            rawValue = app.TimeofDaySlider.Value;
            snappedValue = round(rawValue);  % or use custom logic
            app.TimeofDaySlider.Value = snappedValue;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Load Wattage')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Rate of Charge')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Load Wattage')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_4 = uiaxes(app.UIFigure);
            title(app.UIAxes_4, 'Rate of Charge')
            xlabel(app.UIAxes_4, 'X')
            ylabel(app.UIAxes_4, 'Y')
            zlabel(app.UIAxes_4, 'Z')
            app.UserInputLabel = uilabel(app.UIFigure);
            app.UserInputLabel.Text = 'User Input';
            app.TimeofDaySliderLabel = uilabel(app.UIFigure);
            app.TimeofDaySliderLabel.Text = 'Time of Day';
            app.TimeofDaySlider = uislider(app.UIFigure);
            app.TimeofDaySlider.Limits = [0 2];
            app.TimeofDaySlider.MajorTicks = [0 1 2];
            app.TimeofDaySlider.MajorTickLabels = {'Daytime', 'Transition', 'Nighttime'};
            app.TimeofDaySlider.ValueChangedFcn = createCallbackFcn(app, @TimeofDaySliderValueChanged, true);
            app.Outlet1Label = uilabel(app.UIFigure);
            app.Outlet1Label.Text = 'Outlet 1';
            app.Outlet1Lamp = uilamp(app.UIFigure);
            app.BatterySoCLabel = uilabel(app.UIFigure);
            app.BatterySoCLabel.Text = 'Battery SoC (%)';
            app.BatterySlider = uislider(app.UIFigure);
            app.BatterySlider.MajorTicks = [0 25 50 75 100];
            app.BatterySlider.Value = 69;
            app.TimeofDayStateLampLabel = uilabel(app.UIFigure);
            app.TimeofDayStateLampLabel.Text = 'Time-of-Day State';
            app.TimeofDayStateLamp = uilamp(app.UIFigure);
            app.Outlet2LampLabel = uilabel(app.UIFigure);
            app.Outlet2LampLabel.Text = 'Outlet 2';
            app.Outlet2Lamp = uilamp(app.UIFigure);
            app.BatteryStateLampLabel = uilabel(app.UIFigure);
            app.BatteryStateLampLabel.Text = 'Battery State';
            app.BatteryStateLamp = uilamp(app.UIFigure);
            app.Outlet3LampLabel = uilabel(app.UIFigure);
            app.Outlet3LampLabel.Text = 'Outlet 3';
            app.Outlet3Lamp = uilamp(app.UIFigure);
            app.SolarInputWSliderLabel = uilabel(app.UIFigure);
            app.SolarInputWSliderLabel.Text = 'Solar Input (W)';
            app.SolarSlider = uislider(app.UIFigure);
            app.SolarSlider.Limits = [0 1000];
            app.SolarSlider.Value = 500;
            app.RateofChargeLampLabel = uilabel(app.UIFigure);
            app.RateofChargeLampLabel.Text = 'Rate-of-Charge';
            app.RateofChargeLamp = uilamp(app.UIFigure);
            app.LoadWSliderLabel = uilabel(app.UIFigure);
            app.LoadWSliderLabel.Text = 'Load (W)';
            app.LoadWSlider = uislider(app.UIFigure);
            app.LoadWSlider.Limits = [0 1000];
            app.LoadWSlider.Value = 300;
            app.SystemStateLampLabel = uilabel(app.UIFigure);
            app.SystemStateLampLabel.Text = 'System State';
            app.SystemStateLamp = uilamp(app.UIFigure);
            app.Outlet4LampLabel = uilabel(app.UIFigure);
            app.Outlet4LampLabel.Text = 'Outlet 4';
            app.Outlet4Lamp = uilamp(app.UIFigure);
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.EndButton = uibutton(app.UIFigure, 'push');
            app.EndButton.ButtonPushedFcn = createCallbackFcn(app, @EndButtonPushed, true);
            app.EndButton.Text = 'End';
            app.RunningLampLabel = uilabel(app.UIFigure);
            app.RunningLampLabel.Text = 'Running';
            app.RunningLamp = uilamp(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = simulation

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end