classdef simulation_new < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        LeftPanel                 matlab.ui.container.Panel
        periodLabel               matlab.ui.control.Label
        minuteLabel               matlab.ui.control.Label
        Label_2                   matlab.ui.control.Label
        TimeofDaySliderLabel      matlab.ui.control.Label
        hourLabel                 matlab.ui.control.Label
        SpeedKnob                 matlab.ui.control.DiscreteKnob
        SimulationSpeedKnobLabel  matlab.ui.control.Label
        RunningLamp               matlab.ui.control.Lamp
        RunningLampLabel          matlab.ui.control.Label
        StopButton                matlab.ui.control.Button
        Start48hSimButton         matlab.ui.control.Button
        LoadSlider                matlab.ui.control.Slider
        PeakLoadWLabel            matlab.ui.control.Label
        SolarSlider               matlab.ui.control.Slider
        PeakSolarInputWLabel      matlab.ui.control.Label
        BatterySlider             matlab.ui.control.Slider
        BatterySoCLabel           matlab.ui.control.Label
        SystemInitialInputLabel   matlab.ui.control.Label
        CenterPanel               matlab.ui.container.Panel
        Outlet4DropDown           matlab.ui.control.DropDown
        Outlet4DropDownLabel      matlab.ui.control.Label
        Outlet3DropDown           matlab.ui.control.DropDown
        Outlet3DropDownLabel      matlab.ui.control.Label
        Outlet2DropDown           matlab.ui.control.DropDown
        Outlet2DropDownLabel      matlab.ui.control.Label
        Outlet1DropDown           matlab.ui.control.DropDown
        Outlet1DropDownLabel      matlab.ui.control.Label
        MachineStatesLabel        matlab.ui.control.Label
        Outlet4Lamp               matlab.ui.control.Lamp
        Outlet4LampLabel          matlab.ui.control.Label
        SystemStateLamp           matlab.ui.control.Lamp
        SystemStateLampLabel      matlab.ui.control.Label
        RateofChargeLamp          matlab.ui.control.Lamp
        RateofChargeLampLabel     matlab.ui.control.Label
        Outlet3Lamp               matlab.ui.control.Lamp
        Outlet3LampLabel          matlab.ui.control.Label
        BatteryStateLamp          matlab.ui.control.Lamp
        BatteryStateLampLabel     matlab.ui.control.Label
        Outlet2Lamp               matlab.ui.control.Lamp
        Outlet2LampLabel          matlab.ui.control.Label
        TimeofDayStateLamp        matlab.ui.control.Lamp
        TimeofDayStateLampLabel   matlab.ui.control.Label
        Outlet1Lamp               matlab.ui.control.Lamp
        Outlet1Label              matlab.ui.control.Label
        RightPanel                matlab.ui.container.Panel
        SimulationResultsLabel    matlab.ui.control.Label
        rateOfChargePlot          matlab.ui.control.UIAxes
        loadWattagePlot           matlab.ui.control.UIAxes
        powerPlot                 matlab.ui.control.UIAxes
        batterySoCPlot            matlab.ui.control.UIAxes
        TimeScope                 matlab.ui.scope.TimeScope
        Panel                     matlab.ui.container.Panel
        GridBuddySolarMicrogridSimulationLabel  matlab.ui.control.Label
        Image                     matlab.ui.control.Image
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end


    properties (Access = private)
    Timer   % Timer for simulation loop
    loopCounter = 1; % Counter to track timer iterations
    loopPeriod_s = 0.5;
    solarInput_W;
    batterySoC_pct;
    refBatterySoC_pct;
    discharge_W;
    timeOfDay_State;
    initialLoad;
    simulationSpeed=3600;
    time_vec;
    batteryVoltage = 48;
    solarFactor;
    peakSolar;
    rateOfCharge_A;

    end

    methods (Access = private)

        function fsmTimerCallback(app)
            %% FIRST LOOP
            if app.loopCounter == 1
                app.solarInput_W=[];
                app.batterySoC_pct=[];
                app.discharge_W=[];
                app.timeOfDay_State=[];
                app.initialLoad = app.LoadSlider.Value;
                app.peakSolar = app.SolarSlider.Value;
                app.time_vec=0;
                app.refBatterySoC_pct = app.BatterySlider.Value; 
                app.rateOfCharge_A =[];    

            end
            

            %% UPDATE TIME
            seconds = mod(app.time_vec(end), 86400);
            % Compute hours and minutes
            hours24 = floor(seconds / 3600);
            minutes = floor(mod(seconds, 3600) / 60);

            % Determine AM or PM
            if hours24 < 12
                period = 'AM';
            else
                period = 'PM';
            end

            % Convert to 12-hour format
            hours12 = mod(hours24, 12);
            if hours12 == 0
                hours12 = 12;  % Midnight or noon
            end

            app.hourLabel.Text = sprintf('%02d', hours12);
            app.minuteLabel.Text = sprintf('%02d', minutes);
            app.periodLabel.Text = period;
            %% SOLAR FACTOR & TIME OF DAY STATE
            %Update Solar Factor & Time of Day State
            [app.solarFactor, app.timeOfDay_State(app.loopCounter)]=solarFactorCalc(app.time_vec(end));
            %Update Solar Input
            app.solarInput_W(app.loopCounter) = app.solarFactor*app.peakSolar;
            app.SolarSlider.Value = app.solarInput_W(app.loopCounter);

            %% UPDATE VALUES
            app.batterySoC_pct(app.loopCounter) = app.BatterySlider.Value;
            app.discharge_W(app.loopCounter) = app.LoadSlider.Value;
            app.rateOfCharge_A(app.loopCounter) = (app.solarInput_W(end)-app.discharge_W(end))/app.batteryVoltage;

            if app.batterySoC_pct(end)==100 && app.solarInput_W(end)>app.discharge_W(end)
                app.rateOfCharge_A(end) = 0;
            end
            if app.batterySoC_pct(end)==0 && app.solarInput_W(end)<app.discharge_W(end)
                app.rateOfCharge_A(end) = 0;
                app.discharge_W(end) = app.solarInput_W(end);
            end

            %% UPDATE TIME VECTOR
            if app.loopCounter>1
                app.time_vec(app.loopCounter) = app.time_vec(app.loopCounter-1)+(app.loopPeriod_s*app.simulationSpeed);
            end

            %% UPDATE LOOP COUNT
            app.loopCounter = app.loopCounter +1;

            %% SIMULATION SPEED UPDATE
            switch app.simulationSpeed
                case 1
                    app.loadWattagePlot.XLabel.String = 'Time(s)';
                    app.batterySoCPlot.XLabel.String = 'Time(s)';
                    app.rateOfChargePlot.XLabel.String = 'Time(s)';
                    app.powerPlot.XLabel.String = 'Time(s)';
                    time_vec_final = app.time_vec;
                case 60
                    app.loadWattagePlot.XLabel.String = 'Time(min)';
                    app.batterySoCPlot.XLabel.String = 'Time(min)';
                    app.rateOfChargePlot.XLabel.String = 'Time(min)';
                    app.powerPlot.XLabel.String = 'Time(min)';
                    time_vec_final = app.time_vec/60;
                case 600
                    app.loadWattagePlot.XLabel.String = 'Time(h)';
                    app.batterySoCPlot.XLabel.String = 'Time(h)';
                    app.rateOfChargePlot.XLabel.String = 'Time(h)';
                    app.powerPlot.XLabel.String = 'Time(h)';
                    time_vec_final = app.time_vec/3600;
                case 1800
                    app.loadWattagePlot.XLabel.String = 'Time(h)';
                    app.batterySoCPlot.XLabel.String = 'Time(h)';
                    app.rateOfChargePlot.XLabel.String = 'Time(h)';
                    app.powerPlot.XLabel.String = 'Time(h)';
                    time_vec_final = app.time_vec/3600;
                case 3600
                    app.loadWattagePlot.XLabel.String = 'Time(h)';
                    app.batterySoCPlot.XLabel.String = 'Time(h)';
                    app.rateOfChargePlot.XLabel.String = 'Time(h)';
                    app.powerPlot.XLabel.String = 'Time(h)';
                    time_vec_final = app.time_vec/3600;

                    if length(time_vec_final)>96
                        
                        % turn lamp red
                        app.RunningLamp.Color = [1 0 0];
                        app.BatterySlider.Value = 90;
                        app.SolarSlider.Value = 12000;
                        app.LoadSlider.Value = 6000;
                    
                        % only stop & delete if the timer still exists
                        if isprop(app, 'Timer') && ~isempty(app.Timer) && isvalid(app.Timer)
                            stop(app.Timer);
                            delete(app.Timer);
                        end
                    end 

            end

            %% UPDATE SIMULATION GRAPH

            plot(app.powerPlot, time_vec_final, app.solarInput_W, "r", "LineWidth",2);
            plot(app.loadWattagePlot, time_vec_final, app.discharge_W, 'ro-', 'LineWidth', 2, 'MarkerSize', 4);
            plot(app.rateOfChargePlot, time_vec_final, app.rateOfCharge_A, "bo-", "LineWidth",2, 'MarkerSize', 4);     
            plot(app.batterySoCPlot, time_vec_final, app.batterySoC_pct, "bo-", 'LineWidth', 2, 'MarkerSize', 4);
            hold(app.batterySoCPlot, 'on');
            plot(app.batterySoCPlot, time_vec_final, app.refBatterySoC_pct, "k", 'LineWidth', 1)
            hold(app.batterySoCPlot, 'off');

            yregion(app.batterySoCPlot,0,25,FaceColor=[1.0 0.6 0.6]);
            yregion(app.batterySoCPlot,25,50,FaceColor=[1.0 0.8 0.4]);
            yregion(app.batterySoCPlot,50,75,FaceColor=[1.0 1.0 0.6]);
            yregion(app.batterySoCPlot,75,100,FaceColor=[0.8 1.0 0.8]);
            yline(app.rateOfChargePlot, 0, '-k', 'LineWidth', 2); 
       
            %% RUN NEXT SIMULATION
            [systemState, battery_State, rateOfCharge_State] = runFSM(app.solarInput_W(end), app.batterySoC_pct(end), app.discharge_W(end), app.timeOfDay_State(end));
            [newBatterySoC_pct, loadShedded_W, newRefBatterySoC_pct] = runSim(systemState, app.solarInput_W(end), app.batterySoC_pct(end), app.initialLoad, app.loopPeriod_s, app.simulationSpeed, app.refBatterySoC_pct(end));
            app.LoadSlider.Value = app.initialLoad-loadShedded_W;
            app.BatterySlider.Value = newBatterySoC_pct; 
            app.refBatterySoC_pct(app.loopCounter) = newRefBatterySoC_pct;
            
            %% INDICATOR UPDATE
            % Update System State Indicatior
            switch systemState
                case 'NORMAL'
                    app.SystemStateLamp.Color = [0 1 0]; % Green
                    app.Outlet1Lamp.Color = [0 1 0];
                    app.Outlet2Lamp.Color = [0 1 0];
                    app.Outlet3Lamp.Color = [0 1 0];
                    app.Outlet4Lamp.Color = [0 1 0];
                case 'ALERT1'
                    app.SystemStateLamp.Color = [1 1 0]; % Yellow
                    app.Outlet1Lamp.Color = [0 1 0];
                    app.Outlet2Lamp.Color = [0 1 0];
                    app.Outlet3Lamp.Color = [0 1 0];
                    app.Outlet4Lamp.Color = [1 0 0];
                case 'ALERT2'
                    app.SystemStateLamp.Color = [1 0.5 0]; % Orange
                    app.Outlet1Lamp.Color = [0 1 0];
                    app.Outlet2Lamp.Color = [0 1 0];
                    app.Outlet3Lamp.Color = [1 0 0];
                    app.Outlet4Lamp.Color = [1 0 0];
                case 'CRITICAL'
                    app.SystemStateLamp.Color = [1 0 0]; % Red
                    app.Outlet1Lamp.Color = [1 0 0];
                    app.Outlet2Lamp.Color = [1 0 0];
                    app.Outlet3Lamp.Color = [1 0 0];
                    app.Outlet4Lamp.Color = [1 0 0];
            end             

            % Update Battery State Indicatiors
            switch battery_State
                case 0
                    app.BatteryStateLamp.Color = [0 1 0]; % Green
                case 1
                    app.BatteryStateLamp.Color = [1 1 0]; % Yellow
                case 2
                    app.BatteryStateLamp.Color = [1 0.5 0]; % Orange
                case 3
                    app.BatteryStateLamp.Color = [1 0 0]; % Red
            end

            % Update Rate of Charge Indicatiors
            switch rateOfCharge_State
                case 0
                    app.RateofChargeLamp.Color = [0 1 0]; % Green
                case 1
                    app.RateofChargeLamp.Color = [1 0 0]; % Red
            end
            % Update Time of Day Indicatiors
            switch app.timeOfDay_State(end)
                case 0
                    app.TimeofDayStateLamp.Color = [0 1 0]; % Green
                case 1
                    app.TimeofDayStateLamp.Color = [1 1 0]; % Red
                case 2
                    app.TimeofDayStateLamp.Color = [1 0 0]; % Red
            end
          
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Start48hSimButton
        function Start48hSimButtonPushed(app, event)
            app.RunningLamp.Color = [0 1 0];
            app.loopCounter=1;
            app.Timer = timer( ...
            'ExecutionMode', 'fixedSpacing', ...
            'Period', app.loopPeriod_s, ...
            'TimerFcn', @(~,~) app.fsmTimerCallback());
            start(app.Timer);
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            % turn lamp red
            app.RunningLamp.Color = [1 0 0];
            app.BatterySlider.Value = 90;
            app.SolarSlider.Value = 12000;
            app.LoadSlider.Value = 6000;
        
            % only stop & delete if the timer still exists
            if isprop(app, 'Timer') && ~isempty(app.Timer) && isvalid(app.Timer)
                stop(app.Timer);
                delete(app.Timer);
            end
        
            % clear the handle so we don't try again
            app.Timer = [];
        end

        % Callback function
        function TimeofDaySliderValueChanged(app, event)
            rawValue = app.TimeofDaySlider.Value;
            snappedValue = round(rawValue);  % or use custom logic
            app.TimeofDaySlider.Value = snappedValue;
        end

        % Value changed function: SpeedKnob
        function SpeedKnobValueChanged(app, event)
          
            switch app.SpeedKnob.Value
                case '1X'
                    app.simulationSpeed=1;
                case '60X'
                    app.simulationSpeed=60;
                case '600X'
                    app.simulationSpeed=600;
                case '1800X'
                    app.simulationSpeed=1800;
                case '3600X'
                    app.simulationSpeed=3600;
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {611, 611, 611};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {611, 611};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {483, '1x', 646};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.SystemInitialInputLabel = uilabel(app.LeftPanel);
            app.SystemInitialInputLabel.Text = 'System Initial Input';
            app.BatterySoCLabel = uilabel(app.LeftPanel);
            app.BatterySoCLabel.Text = 'Battery SoC (%)';
            app.BatterySlider = uislider(app.LeftPanel);
            app.BatterySlider.MajorTicks = [0 25 50 75 100];
            app.BatterySlider.Value = 90;
            app.PeakSolarInputWLabel = uilabel(app.LeftPanel);
            app.PeakSolarInputWLabel.Text = 'Peak Solar (W)';
            app.SolarSlider = uislider(app.LeftPanel);
            app.SolarSlider.Limits = [0 16000];
            app.SolarSlider.MajorTicks = [0 3200 6400 9600 12800 16000];
            app.SolarSlider.MajorTickLabels = {'0', '3200', '6400', '9600', '12800', '16000'};
            app.SolarSlider.MinorTicks = [0 320 640 960 1280 1600 1920 2240 2560 2880 3200 3520 3840 4160 4480 4800 5120 5440 5760 6080 6400 6720 7040 7360 7680 8000 8320 8640 8960 9280 9600 9920 10240 10560 10880 11200 11520 11840 12160 12480 12800 13120 13440 13760 14080 14400 14720 15040 15360 15680 16000];
            app.SolarSlider.Value = 12000;
            app.PeakLoadWLabel = uilabel(app.LeftPanel);
            app.PeakLoadWLabel.Text = 'Peak Load (W)';
            app.LoadSlider = uislider(app.LeftPanel);
            app.LoadSlider.Limits = [0 15000];
            app.LoadSlider.MajorTicks = [0 2500 5000 7500 10000 12500 15000];
            app.LoadSlider.MajorTickLabels = {'0', '2500', '5000', '7500', '10000', '12500', '15000'};
            app.LoadSlider.MinorTicks = [0 250 500 750 1000 1250 1500 1750 2000 2250 2500 2750 3000 3250 3500 3750 4000 4250 4500 4750 5000 5250 5500 5750 6000 6250 6500 6750 7000 7250 7500 7750 8000 8250 8500 8750 9000 9250 9500 9750 10000 10250 10500 10750 11000 11250 11500 11750 12000 12250 12500 12750 13000 13250 13500 13750 14000 14250 14500 14750 15000];
            app.LoadSlider.Value = 6000;
            app.Start48hSimButton = uibutton(app.LeftPanel, 'push');
            app.Start48hSimButton.ButtonPushedFcn = createCallbackFcn(app, @Start48hSimButtonPushed, true);
            app.Start48hSimButton.Text = 'Start 48h Sim';
            app.StopButton = uibutton(app.LeftPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.RunningLampLabel = uilabel(app.LeftPanel);
            app.RunningLampLabel.Text = 'Running';
            app.RunningLamp = uilamp(app.LeftPanel);
            app.SimulationSpeedKnobLabel = uilabel(app.LeftPanel);
            app.SimulationSpeedKnobLabel.Text = 'Simulation Speed';
            app.SpeedKnob = uiknob(app.LeftPanel, 'discrete');
            app.SpeedKnob.Items = {'1X', '60X', '600X', '1800X', '3600X'};
            app.SpeedKnob.ValueChangedFcn = createCallbackFcn(app, @SpeedKnobValueChanged, true);
            app.SpeedKnob.Value = '3600X';
            app.hourLabel = uilabel(app.LeftPanel);
            app.hourLabel.Text = '12';
            app.TimeofDaySliderLabel = uilabel(app.LeftPanel);
            app.TimeofDaySliderLabel.Text = 'Time of Day';
            app.Label_2 = uilabel(app.LeftPanel);
            app.Label_2.Text = ':';
            app.minuteLabel = uilabel(app.LeftPanel);
            app.minuteLabel.Text = '00';
            app.periodLabel = uilabel(app.LeftPanel);
            app.periodLabel.Text = 'AM';
            app.CenterPanel = uipanel(app.GridLayout);
            app.Outlet1Label = uilabel(app.CenterPanel);
            app.Outlet1Label.Text = 'Outlet 1';
            app.Outlet1Lamp = uilamp(app.CenterPanel);
            app.TimeofDayStateLampLabel = uilabel(app.CenterPanel);
            app.TimeofDayStateLampLabel.Text = 'Time-of-Day State';
            app.TimeofDayStateLamp = uilamp(app.CenterPanel);
            app.Outlet2LampLabel = uilabel(app.CenterPanel);
            app.Outlet2LampLabel.Text = 'Outlet 2';
            app.Outlet2Lamp = uilamp(app.CenterPanel);
            app.BatteryStateLampLabel = uilabel(app.CenterPanel);
            app.BatteryStateLampLabel.Text = 'Battery State';
            app.BatteryStateLamp = uilamp(app.CenterPanel);
            app.Outlet3LampLabel = uilabel(app.CenterPanel);
            app.Outlet3LampLabel.Text = 'Outlet 3';
            app.Outlet3Lamp = uilamp(app.CenterPanel);
            app.RateofChargeLampLabel = uilabel(app.CenterPanel);
            app.RateofChargeLampLabel.Text = 'Rate-of-Charge';
            app.RateofChargeLamp = uilamp(app.CenterPanel);
            app.SystemStateLampLabel = uilabel(app.CenterPanel);
            app.SystemStateLampLabel.Text = 'System State';
            app.SystemStateLamp = uilamp(app.CenterPanel);
            app.Outlet4LampLabel = uilabel(app.CenterPanel);
            app.Outlet4LampLabel.Text = 'Outlet 4';
            app.Outlet4Lamp = uilamp(app.CenterPanel);
            app.MachineStatesLabel = uilabel(app.CenterPanel);
            app.MachineStatesLabel.Text = 'Machine States';
            app.Outlet1DropDownLabel = uilabel(app.CenterPanel);
            app.Outlet1DropDownLabel.Text = 'Outlet 1:';
            app.Outlet1DropDown = uidropdown(app.CenterPanel);
            app.Outlet1DropDown.Items = {'Appliance'};
            app.Outlet1DropDown.Value = 'Appliance';
            app.Outlet2DropDownLabel = uilabel(app.CenterPanel);
            app.Outlet2DropDownLabel.Text = 'Outlet 2:';
            app.Outlet2DropDown = uidropdown(app.CenterPanel);
            app.Outlet2DropDown.Items = {'Appliance'};
            app.Outlet2DropDown.Value = 'Appliance';
            app.Outlet3DropDownLabel = uilabel(app.CenterPanel);
            app.Outlet3DropDownLabel.Text = 'Outlet 3:';
            app.Outlet3DropDown = uidropdown(app.CenterPanel);
            app.Outlet3DropDown.Items = {'Appliance'};
            app.Outlet3DropDown.Value = 'Appliance';
            app.Outlet4DropDownLabel = uilabel(app.CenterPanel);
            app.Outlet4DropDownLabel.Text = 'Outlet 4:';
            app.Outlet4DropDown = uidropdown(app.CenterPanel);
            app.Outlet4DropDown.Items = {'Appliance'};
            app.Outlet4DropDown.Value = 'Appliance';
            app.RightPanel = uipanel(app.GridLayout);
            app.batterySoCPlot = uiaxes(app.RightPanel);
            title(app.batterySoCPlot, 'Battery SoC')
            xlabel(app.batterySoCPlot, 'Time (s)')
            ylabel(app.batterySoCPlot, 'State of Charge (%)')
            zlabel(app.batterySoCPlot, 'Z')
            app.powerPlot = uiaxes(app.RightPanel);
            title(app.powerPlot, 'Solar Input')
            xlabel(app.powerPlot, 'Time (s)')
            ylabel(app.powerPlot, 'Wattage (W)')
            zlabel(app.powerPlot, 'Z')
            app.loadWattagePlot = uiaxes(app.RightPanel);
            title(app.loadWattagePlot, 'Load Wattage')
            xlabel(app.loadWattagePlot, 'Time (s)')
            ylabel(app.loadWattagePlot, 'Load (W)')
            zlabel(app.loadWattagePlot, 'Z')
            app.rateOfChargePlot = uiaxes(app.RightPanel);
            title(app.rateOfChargePlot, 'Rate of Charge')
            xlabel(app.rateOfChargePlot, 'Time (s)')
            ylabel(app.rateOfChargePlot, 'Charging Current (A)')
            zlabel(app.rateOfChargePlot, 'Z')
            app.SimulationResultsLabel = uilabel(app.RightPanel);
            app.SimulationResultsLabel.Text = 'Simulation Results';
            app.Panel = uipanel(app.UIFigure);
            app.Image = uiimage(app.Panel);
            app.Image.ImageSource = 'GridBuddyLogo.png';
            app.GridBuddySolarMicrogridSimulationLabel = uilabel(app.Panel);
            app.GridBuddySolarMicrogridSimulationLabel.Text = 'GridBuddy Solar Microgrid Simulation';
            app.TimeScope = uitimescope(app.UIFigure);
            app.TimeScope.Title = 'Title';
            app.TimeScope.YLabel = 'Amplitude';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = simulation_new

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end